classdef SelectCandidateTemplates < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectcandidatetemplatesLabel  matlab.ui.control.Label
        Template15CheckBox             matlab.ui.control.CheckBox
        Template14CheckBox             matlab.ui.control.CheckBox
        Template13CheckBox             matlab.ui.control.CheckBox
        Template12CheckBox             matlab.ui.control.CheckBox
        Template11CheckBox             matlab.ui.control.CheckBox
        Template10CheckBox             matlab.ui.control.CheckBox
        Template9CheckBox              matlab.ui.control.CheckBox
        AllROIsdoneExitButton          matlab.ui.control.Button
        Template8CheckBox              matlab.ui.control.CheckBox
        Template7CheckBox              matlab.ui.control.CheckBox
        Template6CheckBox              matlab.ui.control.CheckBox
        Template5CheckBox              matlab.ui.control.CheckBox
        Template4CheckBox              matlab.ui.control.CheckBox
        Template3CheckBox              matlab.ui.control.CheckBox
        Template2CheckBox              matlab.ui.control.CheckBox
        Template1CheckBox              matlab.ui.control.CheckBox
        ROInoSpinner                   matlab.ui.control.Spinner
        ROInoSpinnerLabel              matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Ntemplates              % = options.Ntemplates (input from base workspace)
        Nrois                   % = Nrois (input from base workspace)
        F0_images               % = output_F0_all (input from base workspace)
        CandidateTemplates      % = output_all (input from base workspace)
        CandidateTemplates3D    % = values(5, :) (input from base workspace)
        selectedTemplates       % = selectedTemplates (output to base workspace)
        previousROI             % internal
        currentROI              % internal
    end
    
    methods (Access = private)
        
        function updateFinalTemplate(app)
            m = app.ROInoSpinner.Value;
            for k = 1:app.Ntemplates
                name = ['Template', num2str(k), 'CheckBox'];
                app.selectedTemplates(m, k) = app.(name).Value;
            end
            index = find(app.selectedTemplates(m, :) == 1);
            if sum(index) == 0
                im3 = app.CandidateTemplates3D{1, m}(:, :, 1) .* NaN;
            else
                im3 = mean(app.CandidateTemplates3D{1, m}(:, :, index), 3);
            end
            imagesc(app.UIAxes3, im3)
            colormap(app.UIAxes3, gray);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            % get data and options from base workspace
            app.Ntemplates = evalin('base','options.Ntemplates');
            app.Nrois = evalin('base','Nrois');
            app.selectedTemplates = ones(app.Nrois, app.Ntemplates);
            app.F0_images = evalin('base','output_F0_all');
            app.CandidateTemplates = evalin('base','output_all');
            app.CandidateTemplates3D = evalin('base','values(5, :)');
            % initialize app interface
            app.ROInoSpinner.Value = 1;
            app.currentROI = app.ROInoSpinner.Value;
            app.previousROI = 1; % initialize
            app.ROInoSpinner.Limits = [1, app.Nrois];
            im1 = app.F0_images{1, 1};
            imagesc(app.UIAxes1, im1)
                colormap(app.UIAxes1, gray);
            im2 = app.CandidateTemplates{1, 1};
            imagesc(app.UIAxes2, im2)
                colormap(app.UIAxes2, gray);
            % disable and remove unused checkboxes           
            for k = 15:-1:app.Ntemplates+1
                name = ['Template', num2str(k), 'CheckBox'];
                app.(name).Enable = 0;
                app.(name).Visible = 0;
            end
            updateFinalTemplate(app);
        end

        % Value changed function: ROInoSpinner
        function ROInoSpinnerValueChanged(app, event)
            app.currentROI = app.ROInoSpinner.Value;
            % show images
            im1 = app.F0_images{1, app.currentROI};
            imagesc(app.UIAxes1, im1)
                colormap(app.UIAxes1, gray);
            im2 = app.CandidateTemplates{1, app.currentROI};
            imagesc(app.UIAxes2, im2)
                colormap(app.UIAxes2, gray);
            % update selected candidate templates for the previous ROI
            for k = 1:app.Ntemplates
                name = ['Template', num2str(k), 'CheckBox'];
                app.selectedTemplates(app.previousROI, k) = app.(name).Value;
            end
            % set checkbox values
            for k = 1:app.Ntemplates
                name = ['Template', num2str(k), 'CheckBox'];
                app.(name).Value = app.selectedTemplates(app.currentROI, k);
            end
            %
            app.previousROI = app.currentROI;
            updateFinalTemplate(app);
        end

        % Button pushed function: AllROIsdoneExitButton
        function AllROIsdoneExitButtonPushed(app, event)
            % update selected candidate templates for the current ROI
            app.currentROI = app.ROInoSpinner.Value;
            for k = 1:app.Ntemplates
                name = ['Template', num2str(k), 'CheckBox'];
                app.selectedTemplates(app.currentROI, k) = app.(name).Value;
            end
            assignin('base','selectedTemplates', app.selectedTemplates);
            assignin('base','appexit', 1);
            app.delete
        end

        % Value changed function: Template1CheckBox
        function Template1CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template2CheckBox
        function Template2CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template3CheckBox
        function Template3CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template4CheckBox
        function Template4CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template5CheckBox
        function Template5CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template6CheckBox
        function Template6CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template7CheckBox
        function Template7CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template8CheckBox
        function Template8CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template9CheckBox
        function Template9CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template10CheckBox
        function Template10CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template11CheckBox
        function Template11CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template12CheckBox
        function Template12CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template13CheckBox
        function Template13CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template14CheckBox
        function Template14CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end

        % Value changed function: Template15CheckBox
        function Template15CheckBoxValueChanged(app, event)
            updateFinalTemplate(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'F0 image')
            app.UIAxes1.XLimitMethod = 'tight';
            app.UIAxes1.YLimitMethod = 'tight';
            app.UIAxes1.ZLimitMethod = 'tight';
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XLimitMethod = 'tight';
            app.UIAxes2.YLimitMethod = 'tight';
            app.UIAxes2.ZLimitMethod = 'tight';
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Final template')
            app.UIAxes3.XLimitMethod = 'tight';
            app.UIAxes3.YLimitMethod = 'tight';
            app.UIAxes3.ZLimitMethod = 'tight';
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.ROInoSpinnerLabel = uilabel(app.UIFigure);
            app.ROInoSpinnerLabel.Text = 'ROI no.';
            app.ROInoSpinner = uispinner(app.UIFigure);
            app.ROInoSpinner.ValueChangedFcn = createCallbackFcn(app, @ROInoSpinnerValueChanged, true);
            app.Template1CheckBox = uicheckbox(app.UIFigure);
            app.Template1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template1CheckBoxValueChanged, true);
            app.Template1CheckBox.Text = 'Template 1';
            app.Template1CheckBox.Value = true;
            app.Template2CheckBox = uicheckbox(app.UIFigure);
            app.Template2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template2CheckBoxValueChanged, true);
            app.Template2CheckBox.Text = 'Template 2';
            app.Template2CheckBox.Value = true;
            app.Template3CheckBox = uicheckbox(app.UIFigure);
            app.Template3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template3CheckBoxValueChanged, true);
            app.Template3CheckBox.Text = 'Template 3';
            app.Template3CheckBox.Value = true;
            app.Template4CheckBox = uicheckbox(app.UIFigure);
            app.Template4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template4CheckBoxValueChanged, true);
            app.Template4CheckBox.Text = 'Template 4';
            app.Template4CheckBox.Value = true;
            app.Template5CheckBox = uicheckbox(app.UIFigure);
            app.Template5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template5CheckBoxValueChanged, true);
            app.Template5CheckBox.Text = 'Template 5';
            app.Template5CheckBox.Value = true;
            app.Template6CheckBox = uicheckbox(app.UIFigure);
            app.Template6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template6CheckBoxValueChanged, true);
            app.Template6CheckBox.Text = 'Template 6';
            app.Template6CheckBox.Value = true;
            app.Template7CheckBox = uicheckbox(app.UIFigure);
            app.Template7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template7CheckBoxValueChanged, true);
            app.Template7CheckBox.Text = 'Template 7';
            app.Template7CheckBox.Value = true;
            app.Template8CheckBox = uicheckbox(app.UIFigure);
            app.Template8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template8CheckBoxValueChanged, true);
            app.Template8CheckBox.Text = 'Template 8';
            app.Template8CheckBox.Value = true;
            app.AllROIsdoneExitButton = uibutton(app.UIFigure, 'push');
            app.AllROIsdoneExitButton.ButtonPushedFcn = createCallbackFcn(app, @AllROIsdoneExitButtonPushed, true);
            app.AllROIsdoneExitButton.Text = 'All ROIs done! Exit.';
            app.Template9CheckBox = uicheckbox(app.UIFigure);
            app.Template9CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template9CheckBoxValueChanged, true);
            app.Template9CheckBox.Text = 'Template 9';
            app.Template9CheckBox.Value = true;
            app.Template10CheckBox = uicheckbox(app.UIFigure);
            app.Template10CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template10CheckBoxValueChanged, true);
            app.Template10CheckBox.Text = 'Template 10';
            app.Template10CheckBox.Value = true;
            app.Template11CheckBox = uicheckbox(app.UIFigure);
            app.Template11CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template11CheckBoxValueChanged, true);
            app.Template11CheckBox.Text = 'Template 11';
            app.Template11CheckBox.Value = true;
            app.Template12CheckBox = uicheckbox(app.UIFigure);
            app.Template12CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template12CheckBoxValueChanged, true);
            app.Template12CheckBox.Text = 'Template 12';
            app.Template12CheckBox.Value = true;
            app.Template13CheckBox = uicheckbox(app.UIFigure);
            app.Template13CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template13CheckBoxValueChanged, true);
            app.Template13CheckBox.Text = 'Template 13';
            app.Template13CheckBox.Value = true;
            app.Template14CheckBox = uicheckbox(app.UIFigure);
            app.Template14CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template14CheckBoxValueChanged, true);
            app.Template14CheckBox.Text = 'Template 14';
            app.Template14CheckBox.Value = true;
            app.Template15CheckBox = uicheckbox(app.UIFigure);
            app.Template15CheckBox.ValueChangedFcn = createCallbackFcn(app, @Template15CheckBoxValueChanged, true);
            app.Template15CheckBox.Text = 'Template 15';
            app.Template15CheckBox.Value = true;
            app.SelectcandidatetemplatesLabel = uilabel(app.UIFigure);
            app.SelectcandidatetemplatesLabel.Text = 'Select candidate templates';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectCandidateTemplates(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end